classdef GUIDE1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        text9            matlab.ui.control.Label
        text6            matlab.ui.control.Label
        text7            matlab.ui.control.Label
        PanelC13         matlab.ui.container.Panel
        axesC13          matlab.ui.control.UIAxes
        PanelC12         matlab.ui.container.Panel
        axesC12          matlab.ui.control.UIAxes
        PanelC11         matlab.ui.container.Panel
        axesC11          matlab.ui.control.UIAxes
        PanelC10         matlab.ui.container.Panel
        axesC10          matlab.ui.control.UIAxes
        PanelC9          matlab.ui.container.Panel
        axesC9           matlab.ui.control.UIAxes
        PanelC8          matlab.ui.container.Panel
        axesC8           matlab.ui.control.UIAxes
        PanelC7          matlab.ui.container.Panel
        axesC7           matlab.ui.control.UIAxes
        PanelC6          matlab.ui.container.Panel
        axesC6           matlab.ui.control.UIAxes
        PanelC5          matlab.ui.container.Panel
        axesC5           matlab.ui.control.UIAxes
        PanelC4          matlab.ui.container.Panel
        axesC4           matlab.ui.control.UIAxes
        PanelC3          matlab.ui.container.Panel
        axesC3           matlab.ui.control.UIAxes
        PanelC2          matlab.ui.container.Panel
        axesC2           matlab.ui.control.UIAxes
        PanelC1          matlab.ui.container.Panel
        axesC1           matlab.ui.control.UIAxes
        btnPlantarse     matlab.ui.control.Button
        btnPedir         matlab.ui.control.Button
        PanelResultados  matlab.ui.container.Panel
        editResultados   matlab.ui.control.EditField
        btnSalir         matlab.ui.control.Button
        btnSimulacion    matlab.ui.control.Button
        btnNuevoJuego    matlab.ui.control.Button
        PanelJ13         matlab.ui.container.Panel
        axesJ13          matlab.ui.control.UIAxes
        PanelJ12         matlab.ui.container.Panel
        axesJ12          matlab.ui.control.UIAxes
        PanelJ11         matlab.ui.container.Panel
        axesJ11          matlab.ui.control.UIAxes
        PanelJ10         matlab.ui.container.Panel
        axesJ10          matlab.ui.control.UIAxes
        PanelJ9          matlab.ui.container.Panel
        axesJ9           matlab.ui.control.UIAxes
        PanelJ8          matlab.ui.container.Panel
        axesJ8           matlab.ui.control.UIAxes
        PanelJ7          matlab.ui.container.Panel
        axesJ7           matlab.ui.control.UIAxes
        PanelJ6          matlab.ui.container.Panel
        axesJ6           matlab.ui.control.UIAxes
        PanelJ5          matlab.ui.container.Panel
        axesJ5           matlab.ui.control.UIAxes
        PanelJ4          matlab.ui.container.Panel
        axesJ4           matlab.ui.control.UIAxes
        PanelJ3          matlab.ui.container.Panel
        axesJ3           matlab.ui.control.UIAxes
        PanelJ2          matlab.ui.container.Panel
        axesJ2           matlab.ui.control.UIAxes
        PanelJ1          matlab.ui.container.Panel
        axesJ1           matlab.ui.control.UIAxes
        PanelBaraja      matlab.ui.container.Panel
        axesBaraja       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function setBackgroundWindow(app, hObject)
            %%%% SUPPORT FUNCTIONS %%%%
            %% CONFIGURACION DE FONDO DE PANTALLA
            % Recibe como parametro el objeto componente sobre el que se quieref
            % configurar
            
             % 1) Que el axes cubra toda la figura
                set(hObject, 'Units','normalized', ...
                             'Position',[0 0 1 1]);
            
                % 2) Cargar imagen
                basePath = fileparts(mfilename('fullpath'));
                imgPath  = fullfile(basePath, './green-background1.jpg'); % cambia por tu archivo
                I = imread(imgPath);
            
                % 3) Dibujar imagen estirada
                image('Parent', hObject, 'CData', I);
            
                % Forzar que ocupe TODO el axes
                set(hObject, 'XLim', [1 size(I,2)], ...
                             'YLim', [1 size(I,1)], ...
                             'YDir', 'reverse');   % importante para que no se invierta
            
                % Ajuste para que se estire
                set(hObject, 'Position',[0 0 1 1]);
                axis(hObject, 'off');
                set(hObject, 'Units','normalized');
            
                % 4) Que no bloquee botones
                set(hObject, 'HitTest','off');
                set(findobj(hObject,'Type','image'),'HitTest','off');
            
                % 5) Mandar al fondo
                uistack(hObject,'bottom');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUIDE1_OpeningFcn(app, varargin)
            % --- Executes just before GUIDE1 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUIDE1 (see VARARGIN)
            
            % Choose default command line output for GUIDE1
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            %CONFIGURACIÓN DE VARIALES GLOBALES PERSONALIZADAS
            handles.P_J = 0.0; % PUNTUACIÓN TOTAL DEL JUGADOR
            handles.cartas_J_val = []; % Valores numéricos de las cartas del jugador
            guidata(hObject, handles);
            
            handles.P_C = 0.0; % PUNTUACIÓN TOTAL DE LA CASA
            handles.cartas_C_val = []; % Valores numéricos de las cartas de la casa
            guidata(hObject, handles);
            
            handles.pos_carta_1 = 1; % USAR con VARIABLES Baraja_figura y Bajara_valor
            guidata(hObject, handles);
            
            handles.total_cartas = 40; % auxiliar para el calculo de probabilidades
            guidata(hObject, handles);
            
            handles.pos_ver_c = 1; % contador para desplegar los paneles del jugador y carta
            guidata(hObject, handles);
            
            handles.i = 1;
            guidata(hObject, handles);
            % UIWAIT makes GUIDE1 wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            handles.axesJ13.Toolbar.Visible = 'off';
            al_abrir_ventana(handles);
        end

        % Button pushed function: btnNuevoJuego
        function btnNuevoJuego_Callback(app, event)
            % --- Executes on button press in btnNuevoJuego.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            fprintf("Has presionado el botón de 'JUEVO NUEVO'\n");
            
            % correccion JJ de bug (boton plantarse)
            set(handles.btnPedir, 'Enable', 'on');
            set(handles.btnPlantarse, 'Enable', 'on');
            
            % SE LIMPIAN LOS CONTENEDORES Y SE RESETEAN LAS VARIABLES
            handles = reiniciar_juego(handles);
            guidata(hObject, handles);
            
            %SE HACE EL PROCESO DE CARGA DE BARAJA
            crear_variables_baraja();
            cargar_variables_baraja();
            
            % SE BARAJEA (handles se actualiza con Baraja_fig y Baraja_val barajeadas)
            handles = barajear(hObject, handles);
            
            % SE REPARTE AL JUGADOR (2 cartas)
            for k = 1:2
                carta_fig = handles.Baraja_fig{handles.pos_carta_1};
                carta_val = handles.Baraja_val(handles.pos_carta_1);
            
                handles.cartas_J_val = [handles.cartas_J_val, carta_val];
                mostrar_figuras(carta_fig, handles.pos_ver_c, 'jugador', handles);
            
                handles.pos_carta_1 = handles.pos_carta_1 + 1;
                handles.pos_ver_c   = handles.pos_ver_c   + 1;
            end
            handles.P_J = calcular_puntaje(handles.cartas_J_val);
            guidata(hObject, handles);
            
            % SE REPARTE A LA CASA (2 cartas)
            % Carta 1 (Visible)
            carta_fig_c1 = handles.Baraja_fig{handles.pos_carta_1};
            carta_val_c1 = handles.Baraja_val(handles.pos_carta_1);
            handles.cartas_C_val = [handles.cartas_C_val, carta_val_c1];
            mostrar_figuras(carta_fig_c1, 1, 'casa', handles); % Muestra en la pos 1 de la casa
            handles.pos_carta_1 = handles.pos_carta_1 + 1;
            
            % Carta 2 (Oculta por ahora, solo la guardamos en lógicos, mostraremos el reverso)
            % Asumiendo que tienes una imagen de reverso
            carta_val_c2 = handles.Baraja_val(handles.pos_carta_1);
            handles.cartas_C_val = [handles.cartas_C_val, carta_val_c2];
            mostrar_figuras('Mazo_BlackJack/Especiales/reverso.png', 2, 'casa', handles); % Oculta en pos 2
            handles.pos_carta_1 = handles.pos_carta_1 + 1;
            
            handles.P_C = calcular_puntaje(handles.cartas_C_val);
            guidata(hObject, handles);
            
            % MOSTRAR RESULTADOS INICIALES EN UI
            set(handles.PanelResultados, 'Visible', 'on');
            set(handles.editResultados, 'String', sprintf('Tu puntaje: %.0f', handles.P_J));
            
            fprintf("Puntaje inicial del jugador: %.0f\n", handles.P_J);
            
            % Verificar si el jugador tiene Blackjack de inicio (21 exacto con 2 cartas)
            if handles.P_J == 21
                set(handles.editResultados, 'String', '¡Blackjack! Turno de la casa...');
                set(handles.btnPedir, 'Enable', 'off');
                % Forzamos turno de la casa para ver si empata
                btnPlantarse_Callback(app, eventdata, handles);
            end
        end

        % Button pushed function: btnPedir
        function btnPedir_Callback(app, event)
            % --- Executes on button press in btnPedir.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            fprintf("Has presionado el botón de 'PEDIR MÁS CARTAS'\n");
            
            P_J       = handles.P_J;
            pos_ver_c = handles.pos_ver_c;
            
            % Solo se puede pedir si no se ha pasado y quedan slots visuales
            if (handles.P_J < 21 && pos_ver_c <= 12)
            
                %  Tomar siguiente carta del mazo
                carta_fig = handles.Baraja_fig{handles.pos_carta_1};
                carta_val = handles.Baraja_val(handles.pos_carta_1);
            
                %  Actualizar conjunto de cartas y calcular puntuación real (Aces)
                handles.cartas_J_val = [handles.cartas_J_val, carta_val];
                handles.P_J = calcular_puntaje(handles.cartas_J_val);
                guidata(hObject, handles);
            
                %  Mostrar la carta en el slot visual correspondiente
                mostrar_figuras(carta_fig, pos_ver_c, 'jugador', handles);
                fprintf("Carta pedida: %s  | P_J total: %.0f\n", carta_fig, handles.P_J);
            
                set(handles.PanelResultados,'Visible','on');
                set(handles.editResultados,'String', sprintf('Tu puntaje: %.0f', handles.P_J));
            
                %  Avanzar contadores
                handles.pos_carta_1 = handles.pos_carta_1 + 1;
                handles.pos_ver_c   = pos_ver_c + 1;
                guidata(hObject, handles);
            
                %  Verificar si el jugador se pasó DESPUÉS de sumar
                if handles.P_J > 21
                    fprintf("¡El jugador se ha pasado (Bust)! P_J = %.0f\n", handles.P_J);
                    set(handles.PanelResultados, 'Visible', 'on');
                    set(handles.editResultados, 'String', sprintf('¡Te has pasado! (%.0f) Gana la Casa.', handles.P_J));
            
                    set(handles.btnPedir, 'Enable', 'off');
                    set(handles.btnPlantarse, 'Enable', 'off');
            
                    % Opcional: revelar la carta oculta de la casa para mostrarla
                    carta_oculta_fig = handles.Baraja_fig{handles.pos_carta_1 - 2}; % Reconstruir ruta asumiendo posiciones pos_carta
                    mostrar_figuras(carta_oculta_fig, 2, 'casa', handles);
                elseif handles.P_J == 21
                     % Auto plantarse si llega a 21
                     btnPlantarse_Callback(app, eventdata, handles);
                end
            
            else
                fprintf("No se puede pedir más cartas. P_J=%.1f pos=%d\n", P_J, pos_ver_c);
                set(handles.PanelResultados, 'Visible', 'on');
                set(handles.editResultados, 'String', '¡No puedes pedir más cartas!');
            end
        end

        % Button pushed function: btnPlantarse
        function btnPlantarse_Callback(app, event)
            % --- Executes on button press in btnPlantarse.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            fprintf("Has presionado el botón de 'PLANTARSE'\n");
            
            % --- VALIDACIÓN DE SEGURIDAD ---
            if handles.P_J > 21 || handles.P_J == 0
                return; % No hace nada si ya perdió o no tiene cartas
            end
            % Desactivar botones para que no se presione nada mientras la casa juega
            set(handles.btnPedir, 'Enable', 'off');
            set(handles.btnPlantarse, 'Enable', 'off');
            % -------------------------------
            
            % El jugador se planta
            fprintf("El jugador se planta con P_J = %.0f\n", handles.P_J);
            set(handles.PanelResultados, 'Visible', 'on');
            set(handles.editResultados, 'String', sprintf('Te plantaste con %.0f. Turno de la casa...', handles.P_J));
            
            % REVELAR LA CARTA OCULTA DE LA CASA (Asumiendo que es la segunda carta repartida)
            % Tenemos que reconstruir cuál fue la imagen. Las repartimos hace unos turnos.
            % Como la casa solo tiene 2 cartas en este punto, miramos en Baraja_fig.
            % La carta 1 de la casa estuvo en (pos_inicial_casa), la 2 en (pos_inicial_casa + 1)
            % Como no guardamos la ruta en el array `cartas_C_val`, vamos a buscar la carta en Baraja_val que coincida... o mejor, dado que Baraja no cambia de orden post-barajeo:
            % La casa recibió cartas en los índices 3 y 4 absolutos del juego inicial.
            % Así que la carta oculta es la de índice absoluto 4 del mazo.
            idx_carta2_casa = 4;
            carta_fig_c2 = handles.Baraja_fig{idx_carta2_casa};
            mostrar_figuras(carta_fig_c2, 2, 'casa', handles);
            
            drawnow;
            pause(1.0);
            
            % BUCLE COMPLETO DE LA CASA
            handles = juega_casa(hObject, handles);
        end

        % Button pushed function: btnSalir
        function btnSalir_Callback(app, event)
            % --- Executes on button press in btnSalir.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            fprintf("Has presionado el botón de 'SALIR'\n");
            figure1_CloseRequestFcn(app.figure1, eventdata, handles)
        end

        % Button pushed function: btnSimulacion
        function btnSimulacion_Callback(app, event)
            % --- Executes on button press in btnSimulacion.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            fprintf("Has presionado el boton de 'SIMULACION'\n");
            simulacion;
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            delete(hObject);
            fprintf("Se ha cerrado la ventana con éxito\n");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUIDE1';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.PanelBaraja = uipanel(app.figure1);
            app.PanelBaraja.Tag = 'PanelBaraja';
            app.axesBaraja = uiaxes(app.PanelBaraja);
            app.axesBaraja.XTick = [];
            app.axesBaraja.YTick = [];
            app.axesBaraja.Tag = 'axesBaraja';
            app.PanelJ1 = uipanel(app.figure1);
            app.PanelJ1.Tag = 'PanelJ1';
            app.axesJ1 = uiaxes(app.PanelJ1);
            app.axesJ1.XTick = [];
            app.axesJ1.YTick = [];
            app.axesJ1.Tag = 'axesJ1';
            app.PanelJ2 = uipanel(app.figure1);
            app.PanelJ2.Tag = 'PanelJ2';
            app.axesJ2 = uiaxes(app.PanelJ2);
            app.axesJ2.XTick = [];
            app.axesJ2.YTick = [];
            app.axesJ2.Tag = 'axesJ2';
            app.PanelJ3 = uipanel(app.figure1);
            app.PanelJ3.Tag = 'PanelJ3';
            app.axesJ3 = uiaxes(app.PanelJ3);
            app.axesJ3.XTick = [];
            app.axesJ3.YTick = [];
            app.axesJ3.Tag = 'axesJ3';
            app.PanelJ4 = uipanel(app.figure1);
            app.PanelJ4.Tag = 'PanelJ4';
            app.axesJ4 = uiaxes(app.PanelJ4);
            app.axesJ4.XTick = [];
            app.axesJ4.YTick = [];
            app.axesJ4.Tag = 'axesJ4';
            app.PanelJ5 = uipanel(app.figure1);
            app.PanelJ5.Tag = 'PanelJ5';
            app.axesJ5 = uiaxes(app.PanelJ5);
            app.axesJ5.XTick = [];
            app.axesJ5.YTick = [];
            app.axesJ5.Tag = 'axesJ5';
            app.PanelJ6 = uipanel(app.figure1);
            app.PanelJ6.Tag = 'PanelJ6';
            app.axesJ6 = uiaxes(app.PanelJ6);
            app.axesJ6.XTick = [];
            app.axesJ6.YTick = [];
            app.axesJ6.Tag = 'axesJ6';
            app.PanelJ7 = uipanel(app.figure1);
            app.PanelJ7.Tag = 'PanelJ7';
            app.axesJ7 = uiaxes(app.PanelJ7);
            app.axesJ7.XTick = [];
            app.axesJ7.YTick = [];
            app.axesJ7.Tag = 'axesJ7';
            app.PanelJ8 = uipanel(app.figure1);
            app.PanelJ8.Tag = 'PanelJ8';
            app.axesJ8 = uiaxes(app.PanelJ8);
            app.axesJ8.XTick = [];
            app.axesJ8.YTick = [];
            app.axesJ8.Tag = 'axesJ8';
            app.PanelJ9 = uipanel(app.figure1);
            app.PanelJ9.Tag = 'PanelJ9';
            app.axesJ9 = uiaxes(app.PanelJ9);
            app.axesJ9.XTick = [];
            app.axesJ9.YTick = [];
            app.axesJ9.Tag = 'axesJ9';
            app.PanelJ10 = uipanel(app.figure1);
            app.PanelJ10.Tag = 'PanelJ10';
            app.axesJ10 = uiaxes(app.PanelJ10);
            app.axesJ10.XTick = [];
            app.axesJ10.YTick = [];
            app.axesJ10.Tag = 'axesJ10';
            app.PanelJ11 = uipanel(app.figure1);
            app.PanelJ11.Tag = 'PanelJ11';
            app.axesJ11 = uiaxes(app.PanelJ11);
            app.axesJ11.XTick = [];
            app.axesJ11.YTick = [];
            app.axesJ11.Tag = 'axesJ11';
            app.PanelJ12 = uipanel(app.figure1);
            app.PanelJ12.Tag = 'PanelJ12';
            app.axesJ12 = uiaxes(app.PanelJ12);
            app.axesJ12.XTick = [];
            app.axesJ12.YTick = [];
            app.axesJ12.Tag = 'axesJ12';
            app.PanelJ13 = uipanel(app.figure1);
            app.PanelJ13.Tag = 'PanelJ13';
            app.axesJ13 = uiaxes(app.PanelJ13);
            app.axesJ13.XTick = [];
            app.axesJ13.YTick = [];
            app.axesJ13.Tag = 'axesJ13';
            app.btnNuevoJuego = uibutton(app.figure1, 'push');
            app.btnNuevoJuego.ButtonPushedFcn = createCallbackFcn(app, @btnNuevoJuego_Callback, true);
            app.btnNuevoJuego.Tag = 'btnNuevoJuego';
            app.btnNuevoJuego.Text = 'Nuevo Juego';
            app.btnSimulacion = uibutton(app.figure1, 'push');
            app.btnSimulacion.ButtonPushedFcn = createCallbackFcn(app, @btnSimulacion_Callback, true);
            app.btnSimulacion.Tag = 'btnSimulacion';
            app.btnSimulacion.Text = 'Simulación';
            app.btnSalir = uibutton(app.figure1, 'push');
            app.btnSalir.ButtonPushedFcn = createCallbackFcn(app, @btnSalir_Callback, true);
            app.btnSalir.Tag = 'btnSalir';
            app.btnSalir.Text = 'Salir';
            app.PanelResultados = uipanel(app.figure1);
            app.PanelResultados.Tag = 'PanelResultados';
            app.editResultados = uieditfield(app.PanelResultados, 'text');
            app.editResultados.Tag = 'editResultados';
            app.editResultados.Value = 'Bienvenido jugador, ¿Sale siete y medio?';
            app.btnPedir = uibutton(app.figure1, 'push');
            app.btnPedir.ButtonPushedFcn = createCallbackFcn(app, @btnPedir_Callback, true);
            app.btnPedir.Tag = 'btnPedir';
            app.btnPedir.Visible = 'off';
            app.btnPedir.Text = 'Pedir más cartas';
            app.btnPlantarse = uibutton(app.figure1, 'push');
            app.btnPlantarse.ButtonPushedFcn = createCallbackFcn(app, @btnPlantarse_Callback, true);
            app.btnPlantarse.Tag = 'btnPlantarse';
            app.btnPlantarse.Visible = 'off';
            app.btnPlantarse.Text = 'Plantarse';
            app.PanelC1 = uipanel(app.figure1);
            app.PanelC1.Tag = 'PanelC1';
            app.axesC1 = uiaxes(app.PanelC1);
            app.axesC1.XTick = [];
            app.axesC1.YTick = [];
            app.axesC1.Tag = 'axesC1';
            app.PanelC2 = uipanel(app.figure1);
            app.PanelC2.Tag = 'PanelC2';
            app.axesC2 = uiaxes(app.PanelC2);
            app.axesC2.XTick = [];
            app.axesC2.YTick = [];
            app.axesC2.Tag = 'axesC2';
            app.PanelC3 = uipanel(app.figure1);
            app.PanelC3.Tag = 'PanelC3';
            app.axesC3 = uiaxes(app.PanelC3);
            app.axesC3.XTick = [];
            app.axesC3.YTick = [];
            app.axesC3.Tag = 'axesC3';
            app.PanelC4 = uipanel(app.figure1);
            app.PanelC4.Tag = 'PanelC4';
            app.axesC4 = uiaxes(app.PanelC4);
            app.axesC4.XTick = [];
            app.axesC4.YTick = [];
            app.axesC4.Tag = 'axesC4';
            app.PanelC5 = uipanel(app.figure1);
            app.PanelC5.Tag = 'PanelC5';
            app.axesC5 = uiaxes(app.PanelC5);
            app.axesC5.XTick = [];
            app.axesC5.YTick = [];
            app.axesC5.Tag = 'axesC5';
            app.PanelC6 = uipanel(app.figure1);
            app.PanelC6.Tag = 'PanelC6';
            app.axesC6 = uiaxes(app.PanelC6);
            app.axesC6.XTick = [];
            app.axesC6.YTick = [];
            app.axesC6.Tag = 'axesC6';
            app.PanelC7 = uipanel(app.figure1);
            app.PanelC7.Tag = 'PanelC7';
            app.axesC7 = uiaxes(app.PanelC7);
            app.axesC7.XTick = [];
            app.axesC7.YTick = [];
            app.axesC7.Tag = 'axesC7';
            app.PanelC8 = uipanel(app.figure1);
            app.PanelC8.Tag = 'PanelC8';
            app.axesC8 = uiaxes(app.PanelC8);
            app.axesC8.XTick = [];
            app.axesC8.YTick = [];
            app.axesC8.Tag = 'axesC8';
            app.PanelC9 = uipanel(app.figure1);
            app.PanelC9.Tag = 'PanelC9';
            app.axesC9 = uiaxes(app.PanelC9);
            app.axesC9.XTick = [];
            app.axesC9.YTick = [];
            app.axesC9.Tag = 'axesC9';
            app.PanelC10 = uipanel(app.figure1);
            app.PanelC10.Tag = 'PanelC10';
            app.axesC10 = uiaxes(app.PanelC10);
            app.axesC10.XTick = [];
            app.axesC10.YTick = [];
            app.axesC10.Tag = 'axesC10';
            app.PanelC11 = uipanel(app.figure1);
            app.PanelC11.Tag = 'PanelC11';
            app.axesC11 = uiaxes(app.PanelC11);
            app.axesC11.XTick = [];
            app.axesC11.YTick = [];
            app.axesC11.Tag = 'axesC11';
            app.PanelC12 = uipanel(app.figure1);
            app.PanelC12.Tag = 'PanelC12';
            app.axesC12 = uiaxes(app.PanelC12);
            app.axesC12.XTick = [];
            app.axesC12.YTick = [];
            app.axesC12.Tag = 'axesC12';
            app.PanelC13 = uipanel(app.figure1);
            app.PanelC13.Tag = 'PanelC13';
            app.axesC13 = uiaxes(app.PanelC13);
            app.axesC13.XTick = [];
            app.axesC13.YTick = [];
            app.axesC13.Tag = 'axesC13';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = '';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = {'J'; 'U'; 'G'; 'A'; 'D'; 'O'; 'R'};
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = {'C'; 'A'; 'S'; 'A'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIDE1_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUIDE1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end