classdef simulacion_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        uitoolbar1         matlab.ui.container.Toolbar
        uipanel4           matlab.ui.container.Panel
        BtnSalir           matlab.ui.control.Button
        uipanelResultados  matlab.ui.container.Panel
        editVecesC         matlab.ui.control.EditField
        editVecesJ         matlab.ui.control.EditField
        textVecesJ         matlab.ui.control.Label
        textVecesC         matlab.ui.control.Label
        BtnEmpezar         matlab.ui.control.Button
        uipanelConfig      matlab.ui.container.Panel
        textSim            matlab.ui.control.Label
        textENumero        matlab.ui.control.EditField
        textEstrategiaC    matlab.ui.control.Label
        textEstrategiaJ    matlab.ui.control.Label
        popupEstrategiasC  matlab.ui.control.DropDown
        popupEstrategiasJ  matlab.ui.control.DropDown
        axesGrafica        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function clave = mapear_estrategia(app, nombre)
            %  mapear el nombre de estrategia
            
                switch lower(strtrim(nombre))
                    case {'probabilidad'}
                        clave = 'probabilidad';
                    case 'valor objetivo'
                        clave = 'valor';
                    case 'repartidor'
                        clave = 'repartidor';
                    case 'puntuaje pptimo'
                        clave = 'optimo';
                    case 'doblar apuesta'
                        clave = 'doblar';
                    case 'dividir juego'
                        clave = 'dividir';
                    otherwise
                        clave = 'probabilidad';  % default
                end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function simulacion_OpeningFcn(app, varargin)
            % --- Executes just before simulacion is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;


            % Limpiar campos de resultados
            set(handles.editVecesJ, 'String', '');
            set(handles.editVecesC, 'String', '');
             
            guidata(hObject, handles);
        end

        % Button pushed function: BtnEmpezar
        function BtnEmpezar_Callback(app, event)
            % --- Executes on button press in BtnEmpezar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Leer el numero de iteraciones
            num_iter = str2double(get(handles.textENumero, 'String'));
            if isnan(num_iter) || num_iter <= 0
                msgbox('Error: ingresa un numero valido de iteraciones.', 'Error', 'error');
                return;
            end
            num_iter = round(num_iter);
            
            % Leer las estrategias seleccionadas
            estrategias_J = get(handles.popupEstrategiasJ, 'String');
            idx_J = get(handles.popupEstrategiasJ, 'Value');
            est_J_nombre = estrategias_J{idx_J};
            
            estrategias_C = get(handles.popupEstrategiasC, 'String');
            idx_C = get(handles.popupEstrategiasC, 'Value');
            est_C_nombre = estrategias_C{idx_C};
            
            % Mapear nombres
                      % Mapear estrategia del JUGADOR directamente
nombre_J = lower(strtrim(est_J_nombre));
if contains(nombre_J, 'objetivo')
    est_J = 'Valor Objetivo';
elseif contains(nombre_J, 'repartidor')
    est_J = 'Del repartidor';
elseif contains(nombre_J, 'optimo') || contains(nombre_J, 'puntaje')
    est_J = 'optimo';
elseif contains(nombre_J, 'doblar')
    est_J = 'doblar_apuesta';
elseif contains(nombre_J, 'dividir') || contains(nombre_J, 'split')
    est_J = 'dividir_juego';
else
    est_J = 'optimo';
end

% Mapear estrategia de la CASA directamente
nombre_C = lower(strtrim(est_C_nombre));
if contains(nombre_C, 'probabilidad')
    est_C = 'probabilidad';
else
    est_C = 'valor_objetivo';
end

            
fprintf('\n--- Estrategias seleccionadas ---\n');
fprintf('[JUGADOR] Dropdown: "%s" -> Clave: "%s"\n', est_J_nombre, est_J);
fprintf('[CASA]    Dropdown: "%s" -> Clave: "%s"\n', est_C_nombre, est_C);

% Ejecutar la simulacion
fprintf('Simulando %d partidas...\n', num_iter);

            % Ejecutar la simulacion
            fprintf('\nSimulando %d partidas...\n', num_iter);
            resultados = montecarlo_sim(num_iter, est_J, est_C);
            
            % Mostrar resultados en los edit text
            set(handles.editVecesJ, 'String', sprintf('%d (%.1f%%)', resultados.victorias_jugador, resultados.porcentaje_jugador));
            set(handles.editVecesC, 'String', sprintf('%d (%.1f%%)', resultados.victorias_casa, resultados.porcentaje_casa));

            
           cla(handles.axesGrafica);
            datos = [resultados.victorias_jugador, resultados.victorias_casa];
            etiquetas = { ...
                sprintf('Jugador: %d (%.1f%%)', resultados.victorias_jugador, resultados.porcentaje_jugador), ...
                sprintf('Casa: %d (%.1f%%)', resultados.victorias_casa, resultados.porcentaje_casa) ...
            };
            explode = [1, 0];  % resaltar la porcion del Jugador
            pie3(handles.axesGrafica, datos, explode, etiquetas);
            title(handles.axesGrafica, sprintf('Resultados (%d partidas)', num_iter), 'FontSize', 12);
            colormap(handles.axesGrafica, [0.2 0.6 0.9; 0.9 0.3 0.3]);
            
            fprintf('\n=== Monte Carlo: %d partidas ===\n', num_iter);
            fprintf('Jugador: %.1f%% | Casa: %.1f%% (empates: %d)\n', ...
                resultados.porcentaje_jugador, resultados.porcentaje_casa, resultados.empates);

        end

        % Button pushed function: BtnSalir
        function BtnSalir_Callback(app, event)
            % --- Executes on button press in BtnSalir.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            delete(handles.figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'simulacion';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.axesGrafica = uiaxes(app.figure1);
            app.axesGrafica.Tag = 'axesGrafica';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Tag = 'uipanel4';
            app.uipanelConfig = uipanel(app.uipanel4);
            app.uipanelConfig.Title = 'Configuración de Simulación';
            app.uipanelConfig.Tag = 'uipanelConfig';
            app.popupEstrategiasJ = uidropdown(app.uipanelConfig);
            app.popupEstrategiasJ.Items = {'pEstrategia del Valor Objetivo', 'Estrategia del Repartidor', 'Estrategia del Puntuaje optimo', 'Estrategia Doblar Apuesta', 'Estrategia Dividir Juego', ''};
            app.popupEstrategiasJ.Tag = 'popupEstrategiasJ';
            app.popupEstrategiasJ.Value = 'pEstrategia del Valor Objetivo';
            app.popupEstrategiasC = uidropdown(app.uipanelConfig);
            app.popupEstrategiasC.Items = {'Estrategia de las Probabilidades', 'Estrategia del Valor Objetivo'};
            app.popupEstrategiasC.Tag = 'popupEstrategiasC';
            app.popupEstrategiasC.Value = 'Estrategia de las Probabilidades';
            app.textEstrategiaJ = uilabel(app.uipanelConfig);
            app.textEstrategiaJ.Tag = 'textEstrategiaJ';
            app.textEstrategiaJ.Text = 'Estrategia del Jugador:';
            app.textEstrategiaC = uilabel(app.uipanelConfig);
            app.textEstrategiaC.Tag = 'textEstrategiaC';
            app.textEstrategiaC.Text = 'Estrategía de la Casa:';
            app.textENumero = uieditfield(app.uipanelConfig, 'text');
            app.textENumero.Tag = 'textENumero';
            app.textSim = uilabel(app.uipanelConfig);
            app.textSim.Tag = 'textSim';
            app.textSim.Text = 'Número de Simulaciones: ';
            app.BtnEmpezar = uibutton(app.uipanel4, 'push');
            app.BtnEmpezar.ButtonPushedFcn = createCallbackFcn(app, @BtnEmpezar_Callback, true);
            app.BtnEmpezar.Tag = 'BtnEmpezar';
            app.BtnEmpezar.Text = 'EMPEZAR';
            app.uipanelResultados = uipanel(app.uipanel4);
            app.uipanelResultados.Title = 'Resultados';
            app.uipanelResultados.Tag = 'uipanelResultados';
            app.textVecesC = uilabel(app.uipanelResultados);
            app.textVecesC.Tag = 'textVecesC';
            app.textVecesC.Text = 'Veces que gano la casa: ';
            app.textVecesJ = uilabel(app.uipanelResultados);
            app.textVecesJ.Tag = 'textVecesJ';
            app.textVecesJ.Text = 'Veces que gano el jugador: ';
            app.editVecesJ = uieditfield(app.uipanelResultados, 'text');
            app.editVecesJ.Tag = 'editVecesJ';
            app.editVecesC = uieditfield(app.uipanelResultados, 'text');
            app.editVecesC.Tag = 'editVecesC';
            app.BtnSalir = uibutton(app.uipanel4, 'push');
            app.BtnSalir.ButtonPushedFcn = createCallbackFcn(app, @BtnSalir_Callback, true);
            app.BtnSalir.Tag = 'BtnSalir';
            app.BtnSalir.Text = 'SALIR';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulacion_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)simulacion_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end